classdef FinalGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TMappingButton         matlab.ui.control.Button
        OMappingButton         matlab.ui.control.Button
        ClearMap               matlab.ui.control.Button
        LocalButton            matlab.ui.control.Button
        Recieved               matlab.ui.container.Panel
        RecField               matlab.ui.control.EditField
        Direction              matlab.ui.container.Panel
        PingButton             matlab.ui.control.Button
        Button0                matlab.ui.control.Button
        Button1                matlab.ui.control.Button
        Button2                matlab.ui.control.Button
        Button3                matlab.ui.control.Button
        Pathing                matlab.ui.container.Panel
        RunButton              matlab.ui.control.Button
        PathingField           matlab.ui.control.EditField
        PositionPanel          matlab.ui.container.Panel
        EndY                   matlab.ui.control.NumericEditField
        EndYLabel              matlab.ui.control.Label
        EndX                   matlab.ui.control.NumericEditField
        EndXLabel              matlab.ui.control.Label
        EndLabel               matlab.ui.control.Label
        StartYLabel            matlab.ui.control.Label
        StartXLabel            matlab.ui.control.Label
        StartX                 matlab.ui.control.NumericEditField
        StartY                 matlab.ui.control.NumericEditField
        StartLabel             matlab.ui.control.Label
        PathPlanButton         matlab.ui.control.Button
        MapSelection           matlab.ui.container.ButtonGroup
        TMap2txtButton         matlab.ui.control.RadioButton
        TMap1txtButton         matlab.ui.control.RadioButton
        OGridtxtButton         matlab.ui.control.RadioButton
        MapPanel               matlab.ui.container.Panel
        COMPortPanel           matlab.ui.container.Panel
        ConnectedLamp          matlab.ui.control.Lamp
        Baudrate               matlab.ui.control.DropDown
        BaudrateDropDownLabel  matlab.ui.control.Label
        ComPort                matlab.ui.control.Spinner
        ComPortSpinnerLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        Leo         % LEONARDO Robot
        map         % Text map of the enviroment
        mapImages   % Image map of the enviroment
        mapName     % Name of the Map
        goalPoint   % Position of the goal
        startPoint  % Position of the Robot
        pathing     % Text of pathing
    end

    methods (Access = private)

        function reappear(app)
            app.ConnectedLamp.Color = [0 1 0];
            app.Recieved.Visible = 1;
            app.Direction.Visible = 1;
            app.Pathing.Visible = 1;
            app.PositionPanel.Visible = 1;
            app.MapSelection.Visible = 1;
            app.MapPanel.Visible = 1;
            app.LocalButton.Visible = 1;

        end

        function disappear(app)
            app.ConnectedLamp.Color = [1 0 0];
            %{
            app.Recieved.Visible = 0;
            app.Direction.Visible = 0;
            app.Pathing.Visible = 0;
            app.PositionPanel.Visible = 0;
            app.MapSelection.Visible = 0;
            app.MapPanel.Visible = 0;
            app.LocalButton.Visible = 0;
            %}
        end

        % Set Pathing Arrows
        function setMapPathing(app)
            pathToFollow = app.pathing;
            i = max(size(app.pathing));
            robotPoint = app.startPoint;
            while  i > 1 % run through path like robot would with arrows
                command = pathToFollow(1);
                if command == 'S'
                    fprintf("\nStarting\n");
                elseif command == 'F'
                    fprintf("Forward\n");
                    if mod(robotPoint(3),4) == 0
                        robotPoint(2) = robotPoint(2) - 1;
                    elseif mod(robotPoint(3),4) == 1
                        robotPoint(1) = robotPoint(1) + 1;
                    elseif mod(robotPoint(3),4) == 2
                        robotPoint(2) = robotPoint(2) + 1;
                    elseif mod(robotPoint(3),4) == 3
                        robotPoint(1) = robotPoint(1) - 1;
                    end
                elseif command == 'R'
                    robotPoint(3) = robotPoint(3) + 1;
                elseif command == 'L'
                    robotPoint(3) = robotPoint(3) - 1;
                elseif command == 'U'
                    robotPoint(3) = robotPoint(3) + 2;
                elseif command == 'T'
                end
                pathToFollow = pathToFollow(2:end);
                i = max(size(pathToFollow));
                Image = uiimage(app.MapPanel);
                Image.ImageSource = sprintf('arrow%d.png',mod(robotPoint(3),4));
                Image.Position = [90*(robotPoint(1)-1)+20 90*(4-robotPoint(2))+20 50 50];
                Image.Tag = [int2str(robotPoint(1)),int2str(robotPoint(2))];
                ImageList(max(size(app.pathing))-i) = Image;
            end
            oldPosition = app.startPoint(1:2);
            for ind = 2:max(size(ImageList)) % deletes overlapping arrows
                i=i+1;
                x = str2num(ImageList(ind).Tag(1));
                y = str2num(ImageList(ind).Tag(2));
                if x-oldPosition(1)+y-oldPosition(2)==0
                    delete(ImageList(ind-1));
                end
                oldPosition = [x,y];
            end
            delete(ImageList(1));

        end

        % Sets the Images of the Map
        function setMapGrid(app)
            app.MapPanel.Children.delete;
            for x = 1:size(app.map)
                for y = 1:size(app.map(:,1))
                    Image = uiimage(app.MapPanel);
                    Image.ImageSource = sprintf('Box%d.JPG',app.map(x,y));
                    Image.Position = [90*(y-1) 90*(4-x) 90 90];
                    Image.Tag = [1,int2str(x),int2str(y)];
                    if ~isnan(app.startPoint)
                        for ind = 1:size(app.startPoint(:,1))
                            app.StartX.Value = app.startPoint(ind,1);
                            app.StartY.Value = app.startPoint(ind,2);
                        end
                    end
                    %fprintf('%d \t %d %d\n',app.map(x,y),Image.Position(1), Image.Position(2));

                end
            end

            if ~isnan(app.startPoint)
                for ind = 1:max(size(app.startPoint(:,1)))
                    x = app.startPoint(ind,1);
                    y = app.startPoint(ind,2);
                    Image = uiimage(app.MapPanel);
                    Image.ImageSource = sprintf('Robot%d.png',mod(app.startPoint(1,3),4));
                    Image.Position = [90*(x-1)+20 90*(4-y)+20 50 50];
                    Image.Tag = [0,int2str(x),int2str(y)];
                end
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.Leo = Metric_MobileGUI('LEO');
            try
                app.RecField.Value = app.Leo.setSerial(app.ComPort.Value,str2double(app.Baudrate.Value));
                app.ConnectedLamp.Color = [0 1 0];
            catch
                warning('Com Port does not Exist');
                app.RecField.Value = 'Not Connected';
                app.disappear();
            end
            app.startPoint = [4 4 0];
            app.mapName = app.OGridtxtButton.Text;
            app.map = importdata(app.mapName);
            app.setMapGrid();
        end

        % Selection changed function: MapSelection
        function MapSelectionSelectionChanged(app, event)
            selectedButton = app.MapSelection.SelectedObject;
            app.mapName = selectedButton.Text;
            app.setMapGrid();
        end

        % Value changed function: Baudrate
        function BaudrateValueChanged(app, event)
            value = app.Baudrate.Value;
            try
                app.RecField.Value = app.Leo.setSerial(app.ComPort.Value,str2double(value));
                app.reappear();
            catch
                warning('Com Port does not Exist');
                app.disappear();
            end
        end

        % Callback function: ComPort, ComPort
        function ComPortValueChanged(app, event)
            value = app.ComPort.Value;
            try
                RecField.Value = app.Leo.setSerial(value,str2double(app.Baudrate.Value));
                app.reappear();
            catch
                warning('Com Port does not Exist');
                app.disappear();
            end
        end

        % Button pushed function: PathPlanButton
        function PathPlanButtonPushed(app, event)
            app.startPoint(1) = app.StartX.Value;
            app.startPoint(2) = app.StartY.Value;
            app.goalPoint = [app.EndX.Value, app.EndY.Value];
            app.pathing = app.Leo.pathPlan(app.map, app.startPoint, app.goalPoint);
            app.PathingField.Value = app.pathing;
            app.setMapGrid();
            app.setMapPathing();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            i = max(size(app.pathing));
            while  i > 1
                direction = app.pathing(1);
                app.startPoint = app.Leo.charRunUpdate(direction,app.startPoint);
                app.pathing = app.pathing(2:end);
                app.setMapGrid();
                if i > 2
                    app.setMapPathing();
                end
                i = max(size(app.pathing));
                app.PathingField.Value = app.pathing;
            end

        end

        % Button pushed function: Button0
        function Button0Pushed(app, event)
            if mod(app.startPoint(1,3),4)==0
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            elseif mod(app.startPoint(1,3),4)==1
                app.startPoint = app.Leo.charRunUpdate('L',app.startPoint);
                pause(2);
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            elseif mod(app.startPoint(1,3),4)==2
                app.startPoint = app.Leo.charRunUpdate('U',app.startPoint);
                pause(2);
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            elseif mod(app.startPoint(1,3),4)==3
                app.startPoint = app.Leo.charRunUpdate('R',app.startPoint);
                pause(2);
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            end
            app.setMapGrid();
            app.RecField.Value = 'Moved North';
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            if mod(app.startPoint(1,3),4)==1
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            elseif mod(app.startPoint(1,3),4)==2
                app.startPoint = app.Leo.charRunUpdate('L',app.startPoint);
                pause(2);
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            elseif mod(app.startPoint(1,3),4)==3
                app.startPoint = app.Leo.charRunUpdate('U',app.startPoint);
                pause(2);
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            elseif mod(app.startPoint(1,3),4)==0
                app.startPoint = app.Leo.charRunUpdate('R',app.startPoint);
                pause(2);
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            end
            app.setMapGrid();
            app.RecField.Value = 'Moved East';
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            if mod(app.startPoint(1,3),4)==2
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            elseif mod(app.startPoint(1,3),4)==3
                app.startPoint = app.Leo.charRunUpdate('L',app.startPoint);
                pause(2);
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            elseif mod(app.startPoint(1,3),4)==0
                app.startPoint = app.Leo.charRunUpdate('U',app.startPoint);
                pause(2);
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            elseif mod(app.startPoint(1,3),4)==1
                app.startPoint = app.Leo.charRunUpdate('R',app.startPoint);
                pause(2);
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            end
            app.setMapGrid();
            app.RecField.Value = 'Moved South';
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            if mod(app.startPoint(1,3),4)==3
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            elseif mod(app.startPoint(1,3),4)==0
                app.startPoint = app.Leo.charRunUpdate('L',app.startPoint);
                pause(2);
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            elseif mod(app.startPoint(1,3),4)==1
                app.startPoint = app.Leo.charRunUpdate('U',app.startPoint);
                pause(2);
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            elseif mod(app.startPoint(1,3),4)==2
                app.startPoint = app.Leo.charRunUpdate('R',app.startPoint);
                pause(2);
                app.startPoint = app.Leo.charRunUpdate('F',app.startPoint);
            end
            app.setMapGrid();
            app.RecField.Value = 'Moved West';
        end

        % Button pushed function: PingButton
        function PingButtonPushed(app, event)
            recived = app.Leo.charRunUpdate('C',app.startPoint);
            blockValue = app.Leo.tranformOri(str2num(recived), app.startPoint);
            app.RecField.Value = sprintf('current block value: %i',blockValue);
            app.map(app.startPoint(2),app.startPoint(1)) = bitor(blockValue,app.map(app.startPoint(2),app.startPoint(1)));
            app.setMapGrid();
        end

        % Button pushed function: LocalButton
        function LocalButtonPushed(app, event)
            isFound = 0;
            app.startPoint = [0 0 app.startPoint(3)];
            blockList = [];
            visitedMap = zeros(length(app.map));
            while isFound == 0
                [app.startPoint, isFound, blockList, visitedMap] = app.Leo.localize(app.map,app.startPoint, blockList,visitedMap);
                %disp(app.startPoint());
                app.setMapGrid();
                if isFound == 0
                    app.startPoint(:,3) = app.Leo.moveNext(blockList(1,1),app.startPoint(1,3));
                end
                app.setMapGrid();
            end
        end

        % Button pushed function: ClearMap
        function ClearMapButtonPushed(app, event)
            app.map = [0 0 0 0; 0 0 0 0; 0 0 0 0; 0 0 0 0];
            app.setMapGrid();
        end

        % Button pushed function: OMappingButton
        function OMappingButtonPushed(app, event)
            %OGrid Map button
            app.Leo.StartMap(); % Send mapping command
            newmatrix = [];     % create new matrix
            pause(2);           % wait for map update
            while 1
                pause(8);       % wait
                newmatrix = app.Leo.readmatrix(1);  %update map every time
                if isa(newmatrix,"double")==0       % check if robot finsihed
                    break;
                end
                app.map = newmatrix;                % update map to GUI
                app.setMapGrid();
                %pause(3);
            end
        end

        % Button pushed function: TMappingButton
        function TMappingButtonPushed(app, event)
            %Topo Map
            %same as OGrid
            app.Leo.StartMap();
            newmatrix = [];
            pause(2);
            while 1
                pause(8);
                newmatrix = app.Leo.readmatrix(2);
                if isa(newmatrix,"double")==0
                    break;
                end
                app.map = newmatrix;
                app.setMapGrid();
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.COMPortPanel = uipanel(app.UIFigure);
            app.COMPortPanel.Title = 'COM Port';
            app.ComPortSpinnerLabel = uilabel(app.COMPortPanel);
            app.ComPortSpinnerLabel.Text = 'Com Port';
            app.ComPort = uispinner(app.COMPortPanel);
            app.ComPort.ValueChangingFcn = createCallbackFcn(app, @ComPortValueChanged, true);
            app.ComPort.Limits = [0 Inf];
            app.ComPort.ValueChangedFcn = createCallbackFcn(app, @ComPortValueChanged, true);
            app.ComPort.Value = 9;
            app.BaudrateDropDownLabel = uilabel(app.COMPortPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.Baudrate = uidropdown(app.COMPortPanel);
            app.Baudrate.Items = {'300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '28800', '38400', '57600', '115200', '230400', '250000'};
            app.Baudrate.ValueChangedFcn = createCallbackFcn(app, @BaudrateValueChanged, true);
            app.Baudrate.Value = '115200';
            app.ConnectedLamp = uilamp(app.COMPortPanel);
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.MapSelection = uibuttongroup(app.UIFigure);
            app.MapSelection.SelectionChangedFcn = createCallbackFcn(app, @MapSelectionSelectionChanged, true);
            app.MapSelection.Title = 'Map Selection';
            app.OGridtxtButton = uiradiobutton(app.MapSelection);
            app.OGridtxtButton.Text = 'OGrid.txt';
            app.OGridtxtButton.Value = true;
            app.TMap1txtButton = uiradiobutton(app.MapSelection);
            app.TMap1txtButton.Text = 'TMap1.txt';
            app.TMap2txtButton = uiradiobutton(app.MapSelection);
            app.TMap2txtButton.Text = 'TMap2.txt';
            app.PositionPanel = uipanel(app.UIFigure);
            app.PositionPanel.Title = 'Position';
            app.PathPlanButton = uibutton(app.PositionPanel, 'push');
            app.PathPlanButton.ButtonPushedFcn = createCallbackFcn(app, @PathPlanButtonPushed, true);
            app.PathPlanButton.Text = 'Path Plan';
            app.StartLabel = uilabel(app.PositionPanel);
            app.StartLabel.Text = 'Start';
            app.StartY = uieditfield(app.PositionPanel, 'numeric');
            app.StartY.Value = 2;
            app.StartX = uieditfield(app.PositionPanel, 'numeric');
            app.StartX.Value = 1;
            app.StartXLabel = uilabel(app.PositionPanel);
            app.StartXLabel.Text = 'X:';
            app.StartYLabel = uilabel(app.PositionPanel);
            app.StartYLabel.Text = 'Y:';
            app.EndLabel = uilabel(app.PositionPanel);
            app.EndLabel.Text = 'End';
            app.EndXLabel = uilabel(app.PositionPanel);
            app.EndXLabel.Text = 'X:';
            app.EndX = uieditfield(app.PositionPanel, 'numeric');
            app.EndX.Value = 1;
            app.EndYLabel = uilabel(app.PositionPanel);
            app.EndYLabel.Text = 'Y:';
            app.EndY = uieditfield(app.PositionPanel, 'numeric');
            app.EndY.Value = 4;
            app.Pathing = uipanel(app.UIFigure);
            app.Pathing.Title = 'Pathing';
            app.PathingField = uieditfield(app.Pathing, 'text');
            app.PathingField.Value = 'ST';
            app.RunButton = uibutton(app.Pathing, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Direction = uipanel(app.UIFigure);
            app.Button3 = uibutton(app.Direction, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = '←';
            app.Button2 = uibutton(app.Direction, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '↓';
            app.Button1 = uibutton(app.Direction, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '→';
            app.Button0 = uibutton(app.Direction, 'push');
            app.Button0.ButtonPushedFcn = createCallbackFcn(app, @Button0Pushed, true);
            app.Button0.Text = '↑';
            app.PingButton = uibutton(app.Direction, 'push');
            app.PingButton.ButtonPushedFcn = createCallbackFcn(app, @PingButtonPushed, true);
            app.PingButton.Text = 'C';
            app.Recieved = uipanel(app.UIFigure);
            app.Recieved.Title = 'Recieved';
            app.RecField = uieditfield(app.Recieved, 'text');
            app.RecField.Value = 'ST';
            app.LocalButton = uibutton(app.UIFigure, 'push');
            app.LocalButton.ButtonPushedFcn = createCallbackFcn(app, @LocalButtonPushed, true);
            app.LocalButton.Text = 'Localize';
            app.ClearMap = uibutton(app.UIFigure, 'push');
            app.ClearMap.ButtonPushedFcn = createCallbackFcn(app, @ClearMapButtonPushed, true);
            app.ClearMap.Text = 'Clear Map';
            app.OMappingButton = uibutton(app.UIFigure, 'push');
            app.OMappingButton.ButtonPushedFcn = createCallbackFcn(app, @OMappingButtonPushed, true);
            app.OMappingButton.Text = 'OMapping';
            app.TMappingButton = uibutton(app.UIFigure, 'push');
            app.TMappingButton.ButtonPushedFcn = createCallbackFcn(app, @TMappingButtonPushed, true);
            app.TMappingButton.Text = 'TMapping';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end