classdef MetricFinalGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Pathing                matlab.ui.container.Panel
        RunButton              matlab.ui.control.Button
        PathingField           matlab.ui.control.EditField
        PositionPanel          matlab.ui.container.Panel
        EndY                   matlab.ui.control.NumericEditField
        EndYLabel              matlab.ui.control.Label
        EndX                   matlab.ui.control.NumericEditField
        EndXLabel              matlab.ui.control.Label
        EndLabel               matlab.ui.control.Label
        StartYLabel            matlab.ui.control.Label
        StartXLabel            matlab.ui.control.Label
        StartX                 matlab.ui.control.NumericEditField
        StartY                 matlab.ui.control.NumericEditField
        StartLabel             matlab.ui.control.Label
        PathPlanButton         matlab.ui.control.Button
        MapSelection           matlab.ui.container.ButtonGroup
        TMap2txtButton         matlab.ui.control.RadioButton
        TMap1txtButton         matlab.ui.control.RadioButton
        OGrid1txtButton        matlab.ui.control.RadioButton
        MapPanel               matlab.ui.container.Panel
        COMPortPanel           matlab.ui.container.Panel
        ConnectedLamp          matlab.ui.control.Lamp
        Baudrate               matlab.ui.control.DropDown
        BaudrateDropDownLabel  matlab.ui.control.Label
        ComPort                matlab.ui.control.Spinner
        ComPortSpinnerLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        Leo         % LEONARDO Robot
        map         % Text map of the enviroment
        mapImages   % Image map of the enviroment
        mapName     % Name of the Map
        goalPoint   % Position of the goal
        startPoint  % Position of the Robot
        pathing     % Text of pathing
    end

    methods (Access = private)

        function setMapPathing(app)
            pathToFollow = app.pathing;
            i = max(size(app.pathing));
            robotPoint = app.startPoint;
            while  i > 1
                command = pathToFollow(1);
                if command == 'S'
                    fprintf("\nStarting\n");
                elseif command == 'F'
                    fprintf("Forward\n");
                    if mod(robotPoint(3),4) == 0
                        robotPoint(2) = robotPoint(2) - 1;
                    elseif mod(robotPoint(3),4) == 1
                        robotPoint(1) = robotPoint(1) + 1;
                    elseif mod(robotPoint(3),4) == 2
                        robotPoint(2) = robotPoint(2) + 1;
                    elseif mod(robotPoint(3),4) == 3
                        robotPoint(1) = robotPoint(1) - 1;
                    end
                elseif command == 'R'
                    robotPoint(3) = robotPoint(3) + 1;
                elseif command == 'L'
                    robotPoint(3) = robotPoint(3) - 1;
                elseif command == 'U'
                    robotPoint(3) = robotPoint(3) + 2;
                elseif command == 'T'
                end
                pathToFollow = pathToFollow(2:end);
                i = max(size(pathToFollow));
                Image = uiimage(app.MapPanel);
                Image.ImageSource = sprintf('arrow%d.png',mod(robotPoint(3),4));
                Image.Position = [90*(robotPoint(1)-1)+20 90*(4-robotPoint(2))+20 50 50];
                Image.Tag = [int2str(robotPoint(1)),int2str(robotPoint(2))];
                ImageList(max(size(app.pathing))-i) = Image;
            end
            oldPosition = app.startPoint(1:2);
            for ind = 2:max(size(ImageList));
                i=i+1
                x = str2num(ImageList(ind).Tag(1));
                y = str2num(ImageList(ind).Tag(2));
                if x-oldPosition(1)+y-oldPosition(2)==0
                    delete(ImageList(ind-1));
                end
                oldPosition = [x,y];
            end
            delete(ImageList(1));
            
        end

        % Sets the Images of the Map
        function setMapGrid(app)
            app.map = importdata(app.mapName);
            app.MapPanel.Children.delete;
            for x = 1:size(app.map)
                for y = 1:size(app.map(:,1))
                    Image = uiimage(app.MapPanel);
                    Image.ImageSource = sprintf('Box%d.JPG',app.map(x,y));
                    Image.Position = [90*(y-1) 90*(4-x) 90 90];
                    Image.Tag = [1,int2str(x),int2str(y)];
                    app.mapImages(x+y-1) = Image;
                    if ~isnan(app.startPoint)
                        app.StartX.Value = app.startPoint(1);
                        app.StartY.Value = app.startPoint(2);
                        if x == app.startPoint(2) && y == app.startPoint(1)
                            Image = uiimage(app.MapPanel);
                            Image.ImageSource = sprintf('Robot%d.png',mod(app.startPoint(3),4));
                            Image.Position = [90*(y-1)+20 90*(4-x)+20 50 50];
                            Image.Tag = [0,int2str(x),int2str(y)];
                            app.mapImages(x+y-1,2) = Image;
                        end
                    end
                    %fprintf('%d \t %d %d\n',app.map(x,y),Image.Position(1), Image.Position(2));

                end
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.Leo = Metric_MobileGUI('LEO');
            app.mapName = app.OGrid1txtButton.Text;
            app.startPoint = [1 2 0];
            app.setMapGrid();
        end

        % Selection changed function: MapSelection
        function MapSelectionSelectionChanged(app, event)
            selectedButton = app.MapSelection.SelectedObject;
            app.mapName = selectedButton.Text;
            app.setMapGrid();
        end

        % Value changed function: Baudrate
        function BaudrateValueChanged(app, event)
            value = app.Baudrate.Value;
            try
                app.Leo.setSerial(app.ComPort.Value,str2double(value));
                app.ConnectedLamp.Color = [0 1 0];
            catch
                warning('Com Port does not Exist');
                app.ConnectedLamp.Color = [1 0 0];
            end
        end

        % Value changed function: ComPort
        function ComPortValueChanged(app, event)
            value = app.ComPort.Value;
            try
                app.Leo.setSerial(value,str2double(app.Baudrate.Value));
                app.ConnectedLamp.Color = [0 1 0];
            catch
                warning('Com Port does not Exist');
                app.ConnectedLamp.Color = [1 0 0];
            end
        end

        % Button pushed function: PathPlanButton
        function PathPlanButtonPushed(app, event)
            app.startPoint(1) = app.StartX.Value;
            app.startPoint(2) = app.StartY.Value;
            app.goalPoint = [app.EndX.Value, app.EndY.Value];
            app.pathing = app.Leo.pathPlan(app.map, app.startPoint, app.goalPoint);
            app.PathingField.Value = app.pathing;
            app.setMapGrid();
            app.setMapPathing();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            i = max(size(app.pathing));
            while  i > 1
                direction = app.pathing(1);
                app.startPoint = app.Leo.charRunUpdate(direction,app.startPoint);
                app.pathing = app.pathing(2:end);
                app.setMapGrid();
                if i > 2
                    app.setMapPathing();
                end
                i = max(size(app.pathing));
                app.PathingField.Value = app.pathing;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.COMPortPanel = uipanel(app.UIFigure);
            app.COMPortPanel.Title = 'COM Port';
            app.ComPortSpinnerLabel = uilabel(app.COMPortPanel);
            app.ComPortSpinnerLabel.Text = 'Com Port';
            app.ComPort = uispinner(app.COMPortPanel);
            app.ComPort.Limits = [0 Inf];
            app.ComPort.ValueChangedFcn = createCallbackFcn(app, @ComPortValueChanged, true);
            app.ComPort.Value = 5;
            app.BaudrateDropDownLabel = uilabel(app.COMPortPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.Baudrate = uidropdown(app.COMPortPanel);
            app.Baudrate.Items = {'300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '28800', '38400', '57600', '115200', '230400', '250000'};
            app.Baudrate.ValueChangedFcn = createCallbackFcn(app, @BaudrateValueChanged, true);
            app.Baudrate.Value = '115200';
            app.ConnectedLamp = uilamp(app.COMPortPanel);
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.MapSelection = uibuttongroup(app.UIFigure);
            app.MapSelection.SelectionChangedFcn = createCallbackFcn(app, @MapSelectionSelectionChanged, true);
            app.MapSelection.Title = 'Map Selection';
            app.OGrid1txtButton = uiradiobutton(app.MapSelection);
            app.OGrid1txtButton.Text = 'OGrid1.txt';
            app.OGrid1txtButton.Value = true;
            app.TMap1txtButton = uiradiobutton(app.MapSelection);
            app.TMap1txtButton.Text = 'TMap1.txt';
            app.TMap2txtButton = uiradiobutton(app.MapSelection);
            app.TMap2txtButton.Text = 'TMap2.txt';
            app.PositionPanel = uipanel(app.UIFigure);
            app.PositionPanel.Title = 'Position';
            app.PathPlanButton = uibutton(app.PositionPanel, 'push');
            app.PathPlanButton.ButtonPushedFcn = createCallbackFcn(app, @PathPlanButtonPushed, true);
            app.PathPlanButton.Text = 'Path Plan';
            app.StartLabel = uilabel(app.PositionPanel);
            app.StartLabel.Text = 'Start';
            app.StartY = uieditfield(app.PositionPanel, 'numeric');
            app.StartY.Value = 2;
            app.StartX = uieditfield(app.PositionPanel, 'numeric');
            app.StartX.Value = 1;
            app.StartXLabel = uilabel(app.PositionPanel);
            app.StartXLabel.Text = 'X:';
            app.StartYLabel = uilabel(app.PositionPanel);
            app.StartYLabel.Text = 'Y:';
            app.EndLabel = uilabel(app.PositionPanel);
            app.EndLabel.Text = 'End';
            app.EndXLabel = uilabel(app.PositionPanel);
            app.EndXLabel.Text = 'X:';
            app.EndX = uieditfield(app.PositionPanel, 'numeric');
            app.EndX.Value = 1;
            app.EndYLabel = uilabel(app.PositionPanel);
            app.EndYLabel.Text = 'Y:';
            app.EndY = uieditfield(app.PositionPanel, 'numeric');
            app.EndY.Value = 4;
            app.Pathing = uipanel(app.UIFigure);
            app.Pathing.Title = 'Pathing';
            app.PathingField = uieditfield(app.Pathing, 'text');
            app.PathingField.Value = 'ST';
            app.RunButton = uibutton(app.Pathing, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetricFinalGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end